classdef Controle2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Angulo1SpinnerLabel           matlab.ui.control.Label
        Angulo1Spinner                matlab.ui.control.Spinner
        Angulo2SpinnerLabel           matlab.ui.control.Label
        Angulo2Spinner                matlab.ui.control.Spinner
        Angulo3SpinnerLabel           matlab.ui.control.Label
        Angulo3Spinner                matlab.ui.control.Spinner
        xEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        PosioFinaldoManipuladorLabel  matlab.ui.control.Label
        AngulodeJuntaLabel            matlab.ui.control.Label
        yEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        CinemticaDiretaInsiraonguloLabel  matlab.ui.control.Label
        CinemticaInversaInsiraaPosioLabel  matlab.ui.control.Label
        Angulo1CIEditFieldLabel       matlab.ui.control.Label
        Angulo1CIEditField            matlab.ui.control.NumericEditField
        Angulo2CIEditFieldLabel       matlab.ui.control.Label
        Angulo2CIEditField            matlab.ui.control.NumericEditField
        Angulo3CIEditFieldLabel       matlab.ui.control.Label
        Angulo3CIEditField            matlab.ui.control.NumericEditField
        PxSpinnerLabel                matlab.ui.control.Label
        PxSpinner                     matlab.ui.control.Spinner
        PySpinnerLabel                matlab.ui.control.Label
        PySpinner                     matlab.ui.control.Spinner
        PzSpinnerLabel                matlab.ui.control.Label
        PzSpinner                     matlab.ui.control.Spinner
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        Px;
        Py;
        Pz;
        q;
        Property4 % Description
    end
    
    methods (Access = public)
       
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear a
            delete(instrfind({'Port'},{'COM3'}));
            warning off; clc;
            global a
            a=arduino('COM3');
            a.servoAttach(4);
            a.servoAttach(5);
            a.servoAttach(6);

        end

        % Value changed function: Angulo1Spinner
        function Angulo1SpinnerValueChanged(app, event)
            global a
            value1 = (app.Angulo1Spinner.Value);
            % Atualiza Label do Angulo
            a.servoWrite(6,value1);
            CinematicaDireta;
            app.xEditField.Value=x;
            app.yEditField.Value=y;
            app.zEditField.Value=z;

        end

        % Value changed function: Angulo2Spinner
        function Angulo2SpinnerValueChanged(app, event)
            global a
            value2 = -1*(app.Angulo2Spinner.Value-180);
            % Atualiza Label do Angulo
            a.servoWrite(5,value2);
            
            CinematicaDireta;
            app.xEditField.Value=x;
            app.yEditField.Value=y;
            app.zEditField.Value=z;
            
        end

        % Value changed function: Angulo3Spinner
        function Angulo3SpinnerValueChanged(app, event)
            global a
            value3 = 1*(app.Angulo3Spinner.Value);
            % Atualiza Label do Angulo
            a.servoWrite(4,value3);
            
            CinematicaDireta;
            app.xEditField.Value=x;
            app.yEditField.Value=y;
            app.zEditField.Value=z;
            
        end

        % Value changed function: PxSpinner
        function PxSpinnerValueChanged(app, event)
            global a
            CinematicaInversa;
            

        end

        % Value changed function: PySpinner
        function PySpinnerValueChanged(app, event)
            global a
            CinematicaInversa;
      
        end

        % Value changed function: PzSpinner
        function PzSpinnerValueChanged(app, event)
            global a
            CinematicaInversa;
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Angulo1SpinnerLabel = uilabel(app.UIFigure);
            app.Angulo1SpinnerLabel.Text = 'Angulo 1';
            app.Angulo1Spinner = uispinner(app.UIFigure);
            app.Angulo1Spinner.Step = 10;
            app.Angulo1Spinner.Limits = [0 180];
            app.Angulo1Spinner.ValueChangedFcn = createCallbackFcn(app, @Angulo1SpinnerValueChanged, true);
            app.Angulo1Spinner.Tag = 'angulo_servo1';
            app.Angulo1Spinner.Value = 100;
            app.Angulo2SpinnerLabel = uilabel(app.UIFigure);
            app.Angulo2SpinnerLabel.Text = 'Angulo 2';
            app.Angulo2Spinner = uispinner(app.UIFigure);
            app.Angulo2Spinner.Step = 10;
            app.Angulo2Spinner.Limits = [100 180];
            app.Angulo2Spinner.ValueChangedFcn = createCallbackFcn(app, @Angulo2SpinnerValueChanged, true);
            app.Angulo2Spinner.Tag = 'angulo_servo2';
            app.Angulo2Spinner.Value = 100;
            app.Angulo3SpinnerLabel = uilabel(app.UIFigure);
            app.Angulo3SpinnerLabel.Text = 'Angulo 3';
            app.Angulo3Spinner = uispinner(app.UIFigure);
            app.Angulo3Spinner.Step = 10;
            app.Angulo3Spinner.Limits = [0 180];
            app.Angulo3Spinner.ValueChangedFcn = createCallbackFcn(app, @Angulo3SpinnerValueChanged, true);
            app.Angulo3Spinner.Tag = 'angulo_servo3';
            app.Angulo3Spinner.Value = 140;
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Tag = 'x';
            app.xEditField.Editable = 'off';
            app.PosioFinaldoManipuladorLabel = uilabel(app.UIFigure);
            app.PosioFinaldoManipuladorLabel.Text = 'Posição Final do Manipulador';
            app.AngulodeJuntaLabel = uilabel(app.UIFigure);
            app.AngulodeJuntaLabel.Text = 'Angulo de Junta';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Tag = 'y';
            app.yEditField.Editable = 'off';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.Tag = 'z';
            app.zEditField.Editable = 'off';
            app.CinemticaDiretaInsiraonguloLabel = uilabel(app.UIFigure);
            app.CinemticaDiretaInsiraonguloLabel.Text = 'Cinemática Direta: Insira o ângulo';
            app.CinemticaInversaInsiraaPosioLabel = uilabel(app.UIFigure);
            app.CinemticaInversaInsiraaPosioLabel.Text = 'Cinemática Inversa: Insira a Posição';
            app.Angulo1CIEditFieldLabel = uilabel(app.UIFigure);
            app.Angulo1CIEditFieldLabel.Text = 'Angulo 1 CI';
            app.Angulo1CIEditField = uieditfield(app.UIFigure, 'numeric');
            app.Angulo1CIEditField.RoundFractionalValues = 'on';
            app.Angulo1CIEditField.Tag = 'angulo1CI';
            app.Angulo1CIEditField.Editable = 'off';
            app.Angulo2CIEditFieldLabel = uilabel(app.UIFigure);
            app.Angulo2CIEditFieldLabel.Text = 'Angulo 2 CI';
            app.Angulo2CIEditField = uieditfield(app.UIFigure, 'numeric');
            app.Angulo2CIEditField.RoundFractionalValues = 'on';
            app.Angulo2CIEditField.Tag = 'angulo2CI';
            app.Angulo2CIEditField.Editable = 'off';
            app.Angulo3CIEditFieldLabel = uilabel(app.UIFigure);
            app.Angulo3CIEditFieldLabel.Text = 'Angulo 3 CI';
            app.Angulo3CIEditField = uieditfield(app.UIFigure, 'numeric');
            app.Angulo3CIEditField.RoundFractionalValues = 'on';
            app.Angulo3CIEditField.Tag = 'angulo3CI';
            app.Angulo3CIEditField.Editable = 'off';
            app.PxSpinnerLabel = uilabel(app.UIFigure);
            app.PxSpinnerLabel.Text = 'Px';
            app.PxSpinner = uispinner(app.UIFigure);
            app.PxSpinner.Step = 0.1;
            app.PxSpinner.ValueChangedFcn = createCallbackFcn(app, @PxSpinnerValueChanged, true);
            app.PxSpinner.Value = 0.7;
            app.PySpinnerLabel = uilabel(app.UIFigure);
            app.PySpinnerLabel.Text = 'Py';
            app.PySpinner = uispinner(app.UIFigure);
            app.PySpinner.Step = 0.1;
            app.PySpinner.ValueChangedFcn = createCallbackFcn(app, @PySpinnerValueChanged, true);
            app.PzSpinnerLabel = uilabel(app.UIFigure);
            app.PzSpinnerLabel.Text = 'Pz';
            app.PzSpinner = uispinner(app.UIFigure);
            app.PzSpinner.Step = 0.1;
            app.PzSpinner.ValueChangedFcn = createCallbackFcn(app, @PzSpinnerValueChanged, true);
            app.PzSpinner.Value = 0.55;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controle2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end